classdef BurglarAlarmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RemoveuserButton         matlab.ui.control.Button
        AddnewuserButton         matlab.ui.control.Button
        FacialRecognitionButton  matlab.ui.control.Button
        IsCorrectFace            matlab.ui.control.CheckBox
        UpdateListButton         matlab.ui.control.Button
        AlarmList                matlab.ui.control.ListBox
        BurglarAlarmActivationsListBoxLabel  matlab.ui.control.Label
        ClockLabel               matlab.ui.control.Label
        FontSizeSpinner          matlab.ui.control.Spinner
        FontSizeSpinnerLabel     matlab.ui.control.Label
        EnterSizeButton          matlab.ui.control.StateButton
        DeactivationMessage      matlab.ui.control.Label
        ActivateDeactivateAlarmButtonGroup  matlab.ui.container.ButtonGroup
        DeactivateButton         matlab.ui.control.ToggleButton
        ActivateButton           matlab.ui.control.ToggleButton
        AlarmStatusLabel         matlab.ui.control.Label
        SuccessLabel             matlab.ui.control.Label
        EnterButton              matlab.ui.control.Button
        ENTERPINEditField        matlab.ui.control.NumericEditField
        ENTERPINEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        clockTimer % Description

        serialObj      % Serial object for Arduino
        serialTimer    % Timer to poll Arduino
    end
    
    methods (Access = private)        

        function results = updateClock(app)
            currentTime = datestr(now, 'dd-mmm-yyyy HH:MM:SS');
            app.ClockLabel.Text = currentTime;
        end
        
        
        function checkSerial(app)
            if app.serialObj.NumBytesAvailable > 0
                msg = readline(app.serialObj);
                if strcmp(strtrim(msg), "ALARM_TRIGGERED")
                    app.AlarmStatusLabel.Text = "🚨 Alarm has been triggered!";
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.clockTimer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 1, ...
        'TimerFcn', @(~,~) updateClock(app));
start(app.clockTimer);
            
            try
                app.serialObj = serialport("COM4", 9600);  % Replace with your COM port
            configureTerminator(app.serialObj, "LF");
        
            % Timer to poll serial data
            app.serialTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.5, ...
                'TimerFcn', @(~,~) checkSerial(app));
            start(app.serialTimer);
            catch err
                app.AlarmStatusLabel.Text = "Can't read Arduino..." + err.message;
            end
           
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
        
                pinCode = app.ENTERPINEditField.Value;  % Get PIN from user input
        
                % Open serial communication
                s = serialport("COM4", 9600);  % Update COM port if needed
                configureTerminator(s, "LF");
                flush(s);
                pause(1);  % Let Arduino boot/reset
                writeline(s, string(pinCode));        % Send PIN to Arduino
                pause(1);                     % Wait for Arduino to respond
        
                if s.NumBytesAvailable > 0
                    response = readline(s);
                    response = strip(response);        
                    if strcmp(string(response), "ACCESS GRANTED")
                        app.SuccessLabel.Text = "✅ Access Granted!";

                        app.AlarmStatusLabel.Visible = 'on';     % Show the menu button
                        app.ActivateDeactivateAlarmButtonGroup.Visible = 'on'; % Another button
                        app.DeactivationMessage.Visible = 'on';
                        
                    elseif strcmp(response, "ACCESS DENIED")
                        app.SuccessLabel.Text = "❌ Incorrect PIN.";
                    else
                        app.SuccessLabel.Text = "❓ Unexpected Response: " + response;
                    end
                else
                    app.SuccessLabel.Text = "⚠️ No response from Arduino.";
                end
        
                clear s
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end

        % Callback function
        function EnterButtonPushed2(app, event)
            
        end

        % Value changed function: EnterSizeButton
        function EnterSizeButtonValueChanged(app, event)
            value = app.FontSizeSpinner.Value;
            
            app.SuccessLabel.FontSize = value;
        end

        % Button pushed function: FacialRecognitionButton
        function FacialRecognitionButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end

                streamingFaceRecognition(8,0.5, 0);
        
                isCorrectFace = app.IsCorrectFace.Value; % Get PIN from user input
        
                % Open serial communication
                s = serialport("COM4", 9600);  % Update COM port if needed
                configureTerminator(s, "LF");
                flush(s);
                pause(1);  % Let Arduino boot/reset
     
                if isCorrectFace == true
                    writeline(s, "FACE_OK");        % Send PIN to Arduino
                else
                    writeline(s, "FACE_NOTOK");        % Send PIN to Arduino
                end
        
                clear s
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end

        % Button pushed function: AddnewuserButton
        function AddnewuserButtonPushed(app, event)
            streamingFaceRecognition(8, 0.5, 2);
        end

        % Button pushed function: RemoveuserButton
        function RemoveuserButtonPushed(app, event)
            streamingFaceRecognition(8, 0.5, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ENTERPINEditFieldLabel = uilabel(app.UIFigure);
            app.ENTERPINEditFieldLabel.Text = 'ENTER PIN';
            app.ENTERPINEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Text = 'Success!';
            app.AlarmStatusLabel = uilabel(app.UIFigure);
            app.AlarmStatusLabel.Text = 'Greggs Alarm: ACTIVATED';
            app.ActivateDeactivateAlarmButtonGroup = uibuttongroup(app.UIFigure);
            app.ActivateDeactivateAlarmButtonGroup.Title = 'Activate / Deactivate Alarm';
            app.ActivateDeactivateAlarmButtonGroup.Visible = 'off';
            app.ActivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.ActivateButton.Text = 'Activate';
            app.ActivateButton.Value = true;
            app.DeactivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.DeactivateButton.Text = 'Deactivate';
            app.DeactivationMessage = uilabel(app.UIFigure);
            app.DeactivationMessage.Visible = 'off';
            app.DeactivationMessage.Text = 'The Alarm will remain deactivated until this menu is left.';
            app.EnterSizeButton = uibutton(app.UIFigure, 'state');
            app.EnterSizeButton.ValueChangedFcn = createCallbackFcn(app, @EnterSizeButtonValueChanged, true);
            app.EnterSizeButton.Text = 'Enter Size';
            app.FontSizeSpinnerLabel = uilabel(app.UIFigure);
            app.FontSizeSpinnerLabel.Text = 'Font Size';
            app.FontSizeSpinner = uispinner(app.UIFigure);
            app.FontSizeSpinner.Limits = [12 64];
            app.FontSizeSpinner.RoundFractionalValues = 'on';
            app.FontSizeSpinner.Value = 12;
            app.ClockLabel = uilabel(app.UIFigure);
            app.ClockLabel.Text = 'Imagine Date and Time Here';
            app.BurglarAlarmActivationsListBoxLabel = uilabel(app.UIFigure);
            app.BurglarAlarmActivationsListBoxLabel.Text = 'Burglar Alarm Activations';
            app.AlarmList = uilistbox(app.UIFigure);
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.IsCorrectFace = uicheckbox(app.UIFigure);
            app.FacialRecognitionButton = uibutton(app.UIFigure, 'push');
            app.FacialRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FacialRecognitionButtonPushed, true);
            app.FacialRecognitionButton.Text = '"Facial Recognition"';
            app.AddnewuserButton = uibutton(app.UIFigure, 'push');
            app.AddnewuserButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewuserButtonPushed, true);
            app.AddnewuserButton.Text = 'Add new user';
            app.RemoveuserButton = uibutton(app.UIFigure, 'push');
            app.RemoveuserButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveuserButtonPushed, true);
            app.RemoveuserButton.Text = 'Remove user';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurglarAlarmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end